classdef faceRecognitionApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        Image_Photo       matlab.ui.control.Image
        Image_Live        matlab.ui.control.Image
        DetectionButton   matlab.ui.control.Button
        TakeaphotoButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        liveFeedTimer % Description
    end
    
    properties (Access = public)
        camara % Description
    end
    
    methods (Access = private)
        
        function results = updateLiveFeed(app)
            % Tira o frame mais recente
            liveFrame = snapshot(app.camara); 
            
            %liveFrameDimensao = imresize(liveFrame, [600, 400]);

            % Atualiza a fonte de imagem do componente de visualização ao vivo
            app.Image_Live.ImageSource = liveFrame; 
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            try
                % Criar o objeto webcam
                % Para saber o nome da webcam é fazer webcamlist no MATLAB
                % e aparece as câmaras que estão selecionadas
                % Para isto tudo é preciso instalar a Package: 
                app.camara = webcam("HP TrueVision HD Camera"); 

                try
                    app.camara.Resolution = '640x480'; 
                catch
                    disp('A resolução 640x480 não é suportada. Usando a resolução padrão.');
                end
            catch ME
                uialert(app.UIFigure, ...
                    'Não foi possível aceder à webcam. Verifique se está ligada e disponível.', ...
                    'Erro na Câmara');
                % Termina a função se houver erro
                return; 
            end

            app.liveFeedTimer = timer;
            app.liveFeedTimer.Period = 0.05; 
            app.liveFeedTimer.ExecutionMode = 'fixedRate';
            
            % 2. Define a função a ser executada em cada tick do timer
            app.liveFeedTimer.TimerFcn = @(~, ~) updateLiveFeed(app);
            
            % 3. Inicia o timer
            start(app.liveFeedTimer);

        end

        % Button pushed function: TakeaphotoButton
        function TakeaphotoButtonPushed(app, event)
            % Tira a foto
            foto_botao = snapshot(app.camara);
            % Redimensiona as fotos tiradas para a resolução que é exigida
            % pelo enunciado
            %foto_para_deteccao = imresize(foto_botao, [600, 400]);
            % Mostra a foto no quadrado debaixo
            app.Image_Photo.ImageSource = foto_botao;
            % Torna a foto vísivel
            app.Image_Photo.Visible = 'on';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TakeaphotoButton = uibutton(app.UIFigure, 'push');
            app.TakeaphotoButton.ButtonPushedFcn = createCallbackFcn(app, @TakeaphotoButtonPushed, true);
            app.TakeaphotoButton.Text = 'Take a photo';
            app.DetectionButton = uibutton(app.UIFigure, 'push');
            app.DetectionButton.Text = 'Detection';
            app.Image_Live = uiimage(app.UIFigure);
            app.Image_Photo = uiimage(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = faceRecognitionApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end